classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadRobotButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        modelRobot % Where I will put the loaded puma
        qz = [0     0     0     0     0     0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axes(app.UIAxes);
            cla(app.UIAxes)


            L1 = Link('d',0.275,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L2 = Link('d',0,'a',0.7,'alpha',-pi,'offset',-pi/2,'qlim',[deg2rad(-90),deg2rad(90)]);
            L3 = Link('d',0,'a',0.5,'alpha',pi,'offset',0,'qlim',[deg2rad(-170),deg2rad(170)]);
            L4 = Link('d',0.162,'a',0,'alpha',-pi/2,'offset',-pi/2,'qlim',[deg2rad(-360),deg2rad(360)]);
            L5 = Link('d',0.170,'a',0,'alpha',-pi/2,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            L6 = Link('d',0,'a',0,'alpha',0,'offset',0,'qlim',[deg2rad(-360),deg2rad(360)]);
            app.modelRobot = SerialLink([L1 L2 L3 L4 L5 L6],'name','MOTOMAN HC10DTP');
            q = zeros(1,6);
            plot(app.modelRobot,app.qrPuma)
            drawnow();
            ax = gca;
            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
        end

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)


            qmatrix = jtraj(app.qrPuma,app.qz,20);
            for robotStepIndex = 1:size(qmatrix,1)

                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelRobot.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end

                % Plot it by moving the handle of the robot
                animate(app.modelRobot,qmatrix(robotStepIndex,:),handle)
                drawnow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadRobotButton = uibutton(app.UIFigure, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end